classdef Raman < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        DataTab              matlab.ui.container.Tab
        LoadStandardButton   matlab.ui.control.Button
        LoadDataButton       matlab.ui.control.Button
        SpectrumTab          matlab.ui.container.Tab
        ButtonGroup          matlab.ui.container.ButtonGroup
        NoLinesButton        matlab.ui.control.ToggleButton
        B4CButton            matlab.ui.control.ToggleButton
        SiButton             matlab.ui.control.ToggleButton
        SiB6Button           matlab.ui.control.ToggleButton
        SiCButton            matlab.ui.control.ToggleButton
        IndexLabel           matlab.ui.control.Label
        OneDownButton        matlab.ui.control.Button
        OneUpButton          matlab.ui.control.Button
        SaveSpectrumButton   matlab.ui.control.Button
        SpectrumSlider       matlab.ui.control.Slider
        SpectrumAxes         matlab.ui.control.UIAxes
        DPlotTab             matlab.ui.container.Tab
        YLabel               matlab.ui.control.Label
        XLabel               matlab.ui.control.Label
        EditFieldLabel       matlab.ui.control.Label
        XSlider              matlab.ui.control.Slider
        YSlider              matlab.ui.control.Slider
        XEditField           matlab.ui.control.NumericEditField
        YEditField           matlab.ui.control.NumericEditField
        EditField            matlab.ui.control.NumericEditField
        Save3DPlotButton     matlab.ui.control.Button
        DPlotAxes            matlab.ui.control.UIAxes
        PhasesTab            matlab.ui.container.Tab
        AnalyzePhasesButton  matlab.ui.control.Button
        HypercubeButton      matlab.ui.control.Button
        SavePhasesButton     matlab.ui.control.Button
        PhaseAxes            matlab.ui.control.UIAxes
        StressTab            matlab.ui.container.Tab
        AnalyzeStressButton  matlab.ui.control.Button
        PhaseLabel           matlab.ui.control.Label
        PhaseDropDown        matlab.ui.control.DropDown
        PeakLabel            matlab.ui.control.Label
        PeakDropDown         matlab.ui.control.DropDown
        C1EditFieldLabel     matlab.ui.control.Label
        C1EditField          matlab.ui.control.NumericEditField
        C2EditFieldLabel     matlab.ui.control.Label
        C2EditField          matlab.ui.control.NumericEditField
        SaveStressButton     matlab.ui.control.Button
        StressAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        standard                            % table of standard spectra 

        path                                % location of Raman data (saved figures will be stored here)

        wavenumber                          % column vector of spectral bands
        dcube                               % Y x X x Lambda data cube

        lambda_excitation = 532;            % excitation laser wavelength in nm

        wave_start = 130;                   % spectral bands to be analyzed
        wave_end = 1500;                    % spectral bands to be analyzed

        phase_map                           %
    
%         first_guess = struct('b4c1', [539.63 1.55 541.33 1.28], 'b4c2', [546.00 0.32 547.52 0.37], ...
%                              'b4c3', [553.33 1.55 555.58 1.55 556.68 0.96 557.50 0.64], ...
%                              'b4c4', [560.79 0.80 561.98 1.28 564.66 2.51], 'si', [547.4 1], ...
%                              'sic', [560.76 0.283]);

        first_guess = struct('b4c1', [265.8 54.5 324.1 45.1], 'b4c2', [482.0 11.3 532.7 13.2], ...
                             'b4c3', [724.4 54.5 797.7 54.5 833.5 33.8 859.8 22.6], ...
                             'b4c4', [965.0 28.2 1002.6 45.1 1087.2 88.3], 'si', [528.8 35.3], ...
                             'sic', [964.1 10.0]);

        fit_data                            %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadStandardButton
        function LoadStandardButtonPushed(app, event)
            %% Standard Selection Prompt
            app.UIFigure.Visible = 'off';
            app.PhaseAxes.Visible = 'off';
            [file, temp_path] = uigetfile({'*.txt'});

            standard_name = fullfile(temp_path, file);
            app.standard = readtable(standard_name);

            app.standard = flipud(app.standard);

            [~, start_index] = min(abs(app.standard.Wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.standard.Wavenumber - app.wave_end));

            app.standard = app.standard(start_index:end_index, :);

            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.PhaseAxes.Visible = 'off';
            
            % Clear axes
            cla(app.PhaseAxes)
            set(app.PhaseAxes, 'color', 'none')
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});

            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
 
            %% Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);

            %% Convert raw data to usable format
            % Store Raman shift
            app.wavenumber = data(s_size:-1:1,3);    
            
            % Initialize the cell that stores the Raman data
            app.dcube = zeros(y_size,x_size,s_size);
            
            for j = 1:y_size
                for i = 1:x_size
                    a = (i-1)*s_size + (j-1)*x_size*s_size + 1;
                    b = i*s_size + (j-1)*x_size*s_size;
            
                    app.dcube(j,i,:) = data(b:-1:a,4);
                end
            end
            
            [~, start_index] = min(abs(app.wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.wavenumber - app.wave_end));
            
            app.wavenumber = app.wavenumber(start_index:end_index);
            app.dcube = normalize(app.dcube(:,:,start_index:end_index),3,'range');

            app.UIFigure.Visible = 'on';
        end

        % Value changing function: SpectrumSlider
        function SpectrumSliderValueChanging(app, event)
            changingValue = event.Value;
            
            % Graph Labels
            title_text = '\textbf{Raman Viewer}';
            x_text = '\textbf{Wavelength (nm)}';
            y_text = '\textbf{Intensity}';

            % Delete old plot
            delete(app.pt)

            % Obtain updated index
            index = round(event.Value);

            % Determine x and y from the index
            x = mod(index, app.x_size);
            if x == 0
                x = app.x_size;
            end
            y = ceil(index / app.x_size);
            
            % Update the index label
            Index = num2str(index); Index = append('Index: ', Index);
            X = num2str(x); 
            Y = num2str(y);
            IJ = append('X: ', X, '; Y: ', Y);
            app.IndexLabel.Text = {Index, IJ};
        
            % Update the plot
            app.pt = plot(app.wavelength, squeeze(app.dcube(y,x,:)), 'b', 'LineWidth', 2, 'Parent', app.SpectrumAxes);
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman Spectrum.png');

            % Save the figure
            exportgraphics(app.SpectrumAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: Save3DPlotButton
        function Save3DPlotButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman 3D Plot.png');

            % Save the figure
            exportgraphics(app.SpectrumAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: AnalyzePhasesButton
        function AnalyzePhasesButtonPushed(app, event)
            %% Match Spectra
            score_map(:,:,1) = sidsam(app.dcube, app.standard.B4C);
            score_map(:,:,2) = sidsam(app.dcube, app.standard.Si);
            score_map(:,:,3) = sidsam(app.dcube, app.standard.SiB6);
            score_map(:,:,4) = sidsam(app.dcube, app.standard.SiC);

            %% Phase Map
            [~,app.phase_map] = min(score_map,[],3);
            
            imagesc(app.PhaseAxes,app.phase_map)

            app.PhaseAxes.Colormap = jet(4);
            
            ticks = linspace(1,4,4);
            labels = app.standard.Properties.VariableNames;
            
            colorbar(app.PhaseAxes,'Parent',app.UIFigure,'Ticks',ticks, 'TickLabels',labels(2:end));
            clim(app.PhaseAxes, [0.5 4.5])

            app.PhaseAxes.Title.String = '';

            app.PhaseAxes.XLabel.String = '';
            app.PhaseAxes.XTick = [];
            app.PhaseAxes.XTickLabel = [];
            app.PhaseAxes.XColor = 'none';

            app.PhaseAxes.YLabel.String = '';
            app.PhaseAxes.YTick = [];
            app.PhaseAxes.YTickLabel = [];
            app.PhaseAxes.YColor = 'none';

            app.PhaseAxes.Visible = 'on';
        end

        % Button pushed function: HypercubeButton
        function HypercubeButtonPushed(app, event)
            wavelength = ( (app.lambda_excitation)^(-1) - (app.wavenumber ./ 10^7) ).^(-1);

            hcube = hypercube(app.dcube, wavelength);
            hyperspectralViewer(hcube);
        end

        % Button pushed function: SavePhasesButton
        function SavePhasesButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman Phase Map.png');

            % Save the figure
            exportgraphics(app.SpectrumAxes, figure_name, 'Resolution', 300)
        end

        % Button pushed function: SaveStressButton
        function SaveStressButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Raman Stress Map.png');

            % Save the figure
            export_fig(figure_name,'-transparent','-m5');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.LoadDataButton = uibutton(app.DataTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadStandardButton = uibutton(app.DataTab, 'push');
            app.LoadStandardButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStandardButtonPushed, true);
            app.LoadStandardButton.Text = 'Load Standard';
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.SpectrumAxes = uiaxes(app.SpectrumTab);
            title(app.SpectrumAxes, 'Raman Spectrum')
            xlabel(app.SpectrumAxes, 'Raman Shift (cm^{-1})')
            ylabel(app.SpectrumAxes, 'Intensity')
            zlabel(app.SpectrumAxes, 'Z')
            app.SpectrumSlider = uislider(app.SpectrumTab);
            app.SpectrumSlider.ValueChangingFcn = createCallbackFcn(app, @SpectrumSliderValueChanging, true);
            app.SaveSpectrumButton = uibutton(app.SpectrumTab, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.OneUpButton = uibutton(app.SpectrumTab, 'push');
            app.OneUpButton.Text = 'One Up';
            app.OneDownButton = uibutton(app.SpectrumTab, 'push');
            app.OneDownButton.Text = 'One Down';
            app.IndexLabel = uilabel(app.SpectrumTab);
            app.IndexLabel.Text = {'Index: 1'; 'X: 1'; 'Y: 1'};
            app.ButtonGroup = uibuttongroup(app.SpectrumTab);
            app.SiCButton = uitogglebutton(app.ButtonGroup);
            app.SiCButton.Text = 'SiC';
            app.SiB6Button = uitogglebutton(app.ButtonGroup);
            app.SiB6Button.Text = 'SiB6';
            app.SiButton = uitogglebutton(app.ButtonGroup);
            app.SiButton.Text = 'Si';
            app.B4CButton = uitogglebutton(app.ButtonGroup);
            app.B4CButton.Text = 'B4C';
            app.NoLinesButton = uitogglebutton(app.ButtonGroup);
            app.NoLinesButton.Text = 'No Lines';
            app.NoLinesButton.Value = true;
            app.DPlotTab = uitab(app.TabGroup);
            app.DPlotTab.Title = '3D Plot';
            app.DPlotAxes = uiaxes(app.DPlotTab);
            title(app.DPlotAxes, 'Raman 3D Spectrum')
            xlabel(app.DPlotAxes, 'X Distance')
            ylabel(app.DPlotAxes, 'Raman Shift')
            zlabel(app.DPlotAxes, 'Intensity')
            app.Save3DPlotButton = uibutton(app.DPlotTab, 'push');
            app.Save3DPlotButton.ButtonPushedFcn = createCallbackFcn(app, @Save3DPlotButtonPushed, true);
            app.Save3DPlotButton.Text = 'Save 3D Plot';
            app.EditField = uieditfield(app.DPlotTab, 'numeric');
            app.YEditField = uieditfield(app.DPlotTab, 'numeric');
            app.XEditField = uieditfield(app.DPlotTab, 'numeric');
            app.YSlider = uislider(app.DPlotTab);
            app.YSlider.Limits = [1 100];
            app.YSlider.Value = 1;
            app.XSlider = uislider(app.DPlotTab);
            app.EditFieldLabel = uilabel(app.DPlotTab);
            app.EditFieldLabel.Text = 'α:';
            app.XLabel = uilabel(app.DPlotTab);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.DPlotTab);
            app.YLabel.Text = 'Y:';
            app.PhasesTab = uitab(app.TabGroup);
            app.PhasesTab.Title = 'Phases';
            app.PhaseAxes = uiaxes(app.PhasesTab);
            title(app.PhaseAxes, 'Title')
            xlabel(app.PhaseAxes, 'X')
            ylabel(app.PhaseAxes, 'Y')
            zlabel(app.PhaseAxes, 'Z')
            app.PhaseAxes.Visible = 'off';
            app.SavePhasesButton = uibutton(app.PhasesTab, 'push');
            app.SavePhasesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePhasesButtonPushed, true);
            app.SavePhasesButton.Text = 'Save Phases';
            app.HypercubeButton = uibutton(app.PhasesTab, 'push');
            app.HypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @HypercubeButtonPushed, true);
            app.HypercubeButton.Text = 'Hypercube';
            app.AnalyzePhasesButton = uibutton(app.PhasesTab, 'push');
            app.AnalyzePhasesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzePhasesButtonPushed, true);
            app.AnalyzePhasesButton.Text = 'Analyze Phases';
            app.StressTab = uitab(app.TabGroup);
            app.StressTab.Title = 'Stress';
            app.StressAxes = uiaxes(app.StressTab);
            title(app.StressAxes, 'Title')
            xlabel(app.StressAxes, 'X')
            ylabel(app.StressAxes, 'Y')
            zlabel(app.StressAxes, 'Z')
            app.StressAxes.Visible = 'off';
            app.SaveStressButton = uibutton(app.StressTab, 'push');
            app.SaveStressButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStressButtonPushed, true);
            app.SaveStressButton.Text = 'Save Stress';
            app.C2EditField = uieditfield(app.StressTab, 'numeric');
            app.C2EditFieldLabel = uilabel(app.StressTab);
            app.C2EditFieldLabel.Text = 'C2:';
            app.C1EditField = uieditfield(app.StressTab, 'numeric');
            app.C1EditFieldLabel = uilabel(app.StressTab);
            app.C1EditFieldLabel.Text = 'C1:';
            app.PeakDropDown = uidropdown(app.StressTab);
            app.PeakDropDown.Items = {};
            app.PeakDropDown.Value = {};
            app.PeakLabel = uilabel(app.StressTab);
            app.PeakLabel.Text = 'Peak:';
            app.PhaseDropDown = uidropdown(app.StressTab);
            app.PhaseDropDown.Items = {'', 'B4C', 'Si', 'SiB6', 'SiC'};
            app.PhaseDropDown.Value = '';
            app.PhaseLabel = uilabel(app.StressTab);
            app.PhaseLabel.Text = 'Phase:';
            app.AnalyzeStressButton = uibutton(app.StressTab, 'push');
            app.AnalyzeStressButton.Text = 'Analyze Stress';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end