classdef Raman_3D_Plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EdgeAlphaEditField  matlab.ui.control.NumericEditField
        EdgeAlphaLabel      matlab.ui.control.Label
        YSlider             matlab.ui.control.Slider
        XSlider             matlab.ui.control.Slider
        SaveFigureButton    matlab.ui.control.Button
        ChooseDataButton    matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path
        sorted_data
        x_size
        y_size
        s_size

        x_step = 10
        y_step = 2

        wave_start = 200
        wave_end = 1500

        start_index
        end_index
        disp_range

        % Graph Labels
        title_text = '\textbf{Raman 3D Plot}';
        x_text = '\textbf{X Distance ($\mu$m)}';
        y_text = '\textbf{Y Distance ($\mu$m)}';
        shift_text = '\textbf{Raman Shift (cm$^{-1}$)}';
        intensity_text = '\textbf{Intensity}';

        surf
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseDataButton
        function ChooseDataButtonPushed(app, event)
            %% Data Selection Prompt
            app.UIFigure.Visible = 'off';
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
            
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [app.x_size, ~] = size(x);
            [app.y_size, ~] = size(y);
            [app.s_size, ~] = size(s);

            app.XSlider.Limits = [1 app.x_size];
            app.YSlider.Limits = [1 app.y_size];
            
            %% Convert raw data to plottable format
            app.sorted_data = cell(app.x_size,app.y_size,2);
            app.sorted_data(:,:,1) = mat2cell(data(app.s_size:-1:1,3),app.s_size,1); 
            
            for j = 1:app.y_size
                for i = 1:app.x_size
                    a = (i-1)*app.s_size + (j-1)*app.x_size*app.s_size + 1;
                    b = i*app.s_size + (j-1)*app.x_size*app.s_size;
        
                    app.sorted_data(i,j,2) = mat2cell(normalize(data(b:-1:a,4),1,'range'),app.s_size,1);
                end
            end

            [Intensity,Wavenumber] = deal(zeros(app.s_size,app.y_size));
            for j = 1:app.y_size
                Wavenumber(:,j) = app.sorted_data{1,j,1};
                Intensity(:,j) = app.sorted_data{1,j,2};  
            end

            d = 1:app.y_step:app.y_step * app.y_size;
            Y_Distance = repelem(d,app.s_size,1);
    
            [~,app.start_index] = min(abs(Wavenumber(:,1) - app.wave_start));
            [~,app.end_index] = min(abs(Wavenumber(:,1) - app.wave_end));
            app.disp_range = app.start_index:app.end_index;
                    
            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surf = mesh(Wavenumber(app.disp_range,:), Y_Distance(app.disp_range,:), Intensity(app.disp_range,:), 'Parent', app.UIAxes);

            app.surf.FaceColor = 'interp';
            app.surf.EdgeColor = 'w';
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;
            
            app.UIAxes.Title.String = app.title_text;
            app.UIAxes.Title.Interpreter = 'latex';
            app.UIAxes.Title.FontSize = 18;

            app.UIAxes.XLabel.String = app.shift_text;
            app.UIAxes.XLabel.Interpreter = 'latex';
            app.UIAxes.XLabel.FontSize = 14;

            app.UIAxes.YLabel.String = app.y_text;
            app.UIAxes.YLabel.Interpreter = 'latex';
            app.UIAxes.YLabel.FontSize = 14;
            
            app.UIAxes.ZLabel.String = app.intensity_text;
            app.UIAxes.ZLabel.Interpreter = 'latex';
            app.UIAxes.ZLabel.FontSize = 14;

            app.UIAxes.XLim = [Wavenumber(app.start_index,1) Wavenumber(app.end_index,1)];
            app.UIAxes.YLim = [d(1) d(end)];
            app.UIAxes.ZLim = [0 1];
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            i = round(event.Value);

            [Intensity,Wavenumber] = deal(zeros(app.s_size,app.y_size));
            for j = 1:app.y_size
                Wavenumber(:,j) = app.sorted_data{i,j,1};
                Intensity(:,j) = app.sorted_data{i,j,2};  
            end

            d = 1:app.y_step:app.y_step * app.y_size;
            Y_Distance = repelem(d,app.s_size,1);

            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surf = mesh(Wavenumber(app.disp_range,:), Y_Distance(app.disp_range,:), Intensity(app.disp_range,:), 'Parent', app.UIAxes);

            app.surf.FaceColor = 'interp';
            app.surf.EdgeColor = 'w';
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;

            app.UIAxes.XLabel.String = app.shift_text;
            app.UIAxes.YLabel.String = app.y_text;            

            app.UIAxes.XLim = [Wavenumber(app.start_index,1) Wavenumber(app.end_index,1)];
            app.UIAxes.YLim = [d(1) d(end)];
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            j = round(event.Value);

            [Intensity,Wavenumber] = deal(zeros(app.s_size,app.x_size));
            for i = 1:app.x_size
                Wavenumber(:,i) = app.sorted_data{i,j,1};
                Intensity(:,i) = app.sorted_data{i,j,2};  
            end

            d = 1:app.x_step:app.x_step * app.x_size;
            X_Distance = repelem(d,app.s_size,1);

            %% 3D Mesh of Line Scan
            % Colored faces with white edges
            app.surf = mesh(X_Distance(app.disp_range,:), Wavenumber(app.disp_range,:), Intensity(app.disp_range,:), 'Parent', app.UIAxes);

            app.surf.FaceColor = 'interp';
            app.surf.EdgeColor = 'w';
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;

            app.UIAxes.XLabel.String = app.x_text;
            app.UIAxes.YLabel.String = app.shift_text;

            app.UIAxes.XLim = [d(1) d(end)];
            app.UIAxes.YLim = [Wavenumber(app.start_index,1) Wavenumber(app.end_index,1)];
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            exportgraphics(app.UIAxes, fullfile(app.path,'Raman_3D_Plot.png'), 'Resolution', 300)
        end

        % Value changed function: EdgeAlphaEditField
        function EdgeAlphaEditFieldValueChanged(app, event)
            app.surf.EdgeAlpha = app.EdgeAlphaEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChooseDataButton = uibutton(app.UIFigure, 'push');
            app.ChooseDataButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataButtonPushed, true);
            app.ChooseDataButton.Text = 'Choose Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.EdgeAlphaLabel = uilabel(app.UIFigure);
            app.EdgeAlphaLabel.Text = 'Edge Alpha:';
            app.EdgeAlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgeAlphaEditField.Limits = [0 1];
            app.EdgeAlphaEditField.ValueChangedFcn = createCallbackFcn(app, @EdgeAlphaEditFieldValueChanged, true);
            app.EdgeAlphaEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_3D_Plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end