classdef Raman_Phase_Map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RelativeIntesityEditField  matlab.ui.control.NumericEditField
        RelativeIntesityLabel      matlab.ui.control.Label
        SaveFigureButton           matlab.ui.control.Button
        LoadDataButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path                        % location of Raman data (saved figures will be stored here)
        saved_figs = 0;             % counter for number of saved figures

        % Specify excitation laser wavelength in nm
        lambda_excitation = 532;

        % Specify spectral bands to be analyzed
        wave_start = 535.74;
        wave_end = 595;
    
        map_data                    % three dimensional cell where (X, Y, 1) contains a column array of wavenumbers
                                    % and (X, Y, 2) cointains a column array of intensities
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)
            set(app.UIAxes, 'color', 'none')
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
 
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [app.x_size, ~] = size(x);
            [app.y_size, ~] = size(y);
            [s_size, ~] = size(s);

            %% Convert raw data to usable format
            % Store Raman shift
            wavelength = data(s_size:-1:1,3);
            
            % Convert Raman shift to Raman wavelength
            wavelength = ( (app.lambda_excitation)^(-1) - (wavelength ./ 10^7) ).^(-1);
            
            % Initialize the cell that stores the Raman data
            dcube = zeros(y_size,x_size,s_size);
            
            for j = 1:y_size
                for i = 1:x_size
                    a = (i-1)*s_size + (j-1)*x_size*s_size + 1;
                    b = i*s_size + (j-1)*x_size*s_size;
            
                    dcube(j,i,:) = data(b:-1:a,4);
                end
            end
            
            [~, start_index] = min(abs(wavelength - app.wave_start));
            [~, end_index] = min(abs(wavelength - app.wave_end));
            
            wavelength = wavelength(start_index:end_index);
            dcube = normalize(dcube(:,:,start_index:end_index),3,'range');
            
            hcube = hypercube(dcube, wavelength);

            %% Match Spectra
            scoreMap(:,:,1) = sidsam(dcube, phaseLibrary(1).Reflectance);
            scoreMap(:,:,2) = sidsam(dcube, phaseLibrary(2).Reflectance);
            scoreMap(:,:,3) = sidsam(dcube, phaseLibrary(3).Reflectance);
            scoreMap(:,:,4) = sidsam(dcube, phaseLibrary(4).Reflectance);

            %% Class Map
            [~,classMap] = min(scoreMap,[],3);
            
            figure
            imagesc(classMap)
            
            ticks = linspace(1,4,4);
            colormap(jet(4))
            colorbar('Ticks',ticks,'TickLabels',[phaseLibrary.Name])
            clim([0.5 4.5])

            % Negative values are compression. Positive values are tension. Units are in MPa
            color_bar = colorbar(app.UIAxes,'southoutside','Parent',app.UIFigure);
            color_bar.Limits = [-lim lim];
            color_bar.FontSize = 18;

            app.UIAxes.Title.String = '';

            app.UIAxes.XLabel.String = '';
            app.UIAxes.XLim = [1 app.x_size];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = [];
            app.UIAxes.XColor = 'none';

            app.UIAxes.YLabel.String = '';
            app.UIAxes.YLim = [1 app.y_size];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = [];
            app.UIAxes.YColor = 'none';

            app.UIAxes.Visible = 'on';
        end

        % Value changed function: RelativeIntesityEditField
        function RelativeIntesityEditFieldValueChanged(app, event)
            app.UIAxes.Visible = 'off';
            CMax = app.RelativeIntesityEditField.Value;

            app.UIAxes.Visible = 'on';
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            % Increment the number of saved figures counter
            app.saved_figs = app.saved_figs + 1;

            % Generate the new figure name
            figure_name = append(app.path, 'Raman Stress Map - ', int2str(app.saved_figs), '.png');

            % Save the figure
            export_fig(figure_name,'-transparent','-m5');
%             exportgraphics(app.UIAxes,figure_name,'Resolution',300)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.RelativeIntesityLabel = uilabel(app.UIFigure);
            app.RelativeIntesityLabel.Text = 'Relative Intesity:';
            app.RelativeIntesityEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelativeIntesityEditField.Limits = [1 Inf];
            app.RelativeIntesityEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeIntesityEditFieldValueChanged, true);
            app.RelativeIntesityEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Phase_Map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end