classdef Raman_Stress_Map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        C2EditField          matlab.ui.control.NumericEditField
        C2EditFieldLabel     matlab.ui.control.Label
        C1EditField          matlab.ui.control.NumericEditField
        CLimLabel            matlab.ui.control.Label
        PeakDropDown         matlab.ui.control.DropDown
        PeakLabel            matlab.ui.control.Label
        PhaseDropDown        matlab.ui.control.DropDown
        PhaseLabel           matlab.ui.control.Label
        LoadStandardButton   matlab.ui.control.Button
        SaveStressMapButton  matlab.ui.control.Button
        LoadDataButton       matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path                                % location of Raman data (saved figures will be stored here)
        saved_figs = 0;                     % counter for number of saved figures

        lambda_excitation = 532;            % excitation laser wavelength in nm

        wave_start = 130;                   % spectral bands to be analyzed
        wave_end = 1500;                    % spectral bands to be analyzed
        
        standard                            % table of standard spectra 

        wavenumber                          % column vector of spectral bands
        dcube                               % Y x X x Lambda data cube

        phase_map
    
        first_guess = struct('b4c1', [539.63 1.55 541.33 1.28], 'b4c2', [546.00 0.32 547.52 0.37], ...
                             'b4c3', [553.33 1.55 555.58 1.55 556.68 0.96 557.50 0.64], ...
                             'b4c4', [560.79 0.80 561.98 1.28 564.66 2.51], 'si', [547.4 1], ...
                             'sic', [560.76 0.283]);

        fit_data
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadStandardButton
        function LoadStandardButtonPushed(app, event)
            %% Standard Selection Prompt
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            [file, temp_path] = uigetfile({'*.txt'});

            standard_name = fullfile(temp_path, file);
            app.standard = readtable(standard_name);

            app.standard = flipud(app.standard);

            [~, start_index] = min(abs(app.standard.Wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.standard.Wavenumber - app.wave_end));

            app.standard = app.standard(start_index:end_index, :);

            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.UIAxes.Visible = 'off';
            
            % Clear axes
            cla(app.UIAxes)
            set(app.UIAxes, 'color', 'none')
            
            %% Data Selection Prompt
            [file, app.path] = uigetfile({'*.txt'});

            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
 
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);

            %% Convert raw data to usable format
            % Store Raman shift
            app.wavenumber = data(s_size:-1:1,3);    
            
            % Initialize the cell that stores the Raman data
            app.dcube = zeros(y_size,x_size,s_size);
            
            for j = 1:y_size
                for i = 1:x_size
                    a = (i-1)*s_size + (j-1)*x_size*s_size + 1;
                    b = i*s_size + (j-1)*x_size*s_size;
            
                    app.dcube(j,i,:) = data(b:-1:a,4);
                end
            end
            
            [~, start_index] = min(abs(app.wavenumber - app.wave_start));
            [~, end_index] = min(abs(app.wavenumber - app.wave_end));
            
            app.wavenumber = app.wavenumber(start_index:end_index);
            app.dcube = normalize(app.dcube(:,:,start_index:end_index),3,'range');

            %% Match Spectra
            score_map(:,:,1) = sidsam(app.dcube, app.standard.B4C);
            score_map(:,:,2) = sidsam(app.dcube, app.standard.Si);
            score_map(:,:,3) = sidsam(app.dcube, app.standard.SiB6);
            score_map(:,:,4) = sidsam(app.dcube, app.standard.SiC);

            %% Class Map
            [~,app.phase_map] = min(score_map,[],3);

            app.fit_data = cell(y_size,x_size);

            for j = 1:y_size
                for i = 1:x_size
                    if classMap(j,i) == 1
                        [~,b4c1_start] = min(abs(wavelength - 536.3));
                        [~,b4c1_end] = min(abs(wavelength - 544.0));
                        
                        [~,b4c2_start] = min(abs(wavelength - 545.0));
                        [~,b4c2_end] = min(abs(wavelength - 548.8));
                        
                        [~,b4c4_start] = min(abs(wavelength - 558.9));
                        [~,b4c4_end] = min(abs(wavelength - 571.53));
                        
                        signal1 = [wavelength(b4c1_start:b4c1_end) squeeze(app.dcube(j,i,b4c1_start:b4c1_end))];
                        signal2 = [wavelength(b4c2_start:b4c2_end) squeeze(app.dcube(j,i,b4c2_start:b4c2_end))];
                        signal4 = [wavelength(b4c4_start:b4c4_end) squeeze(app.dcube(j,i,b4c4_start:b4c4_end))];
                        
                        [FitResults1, ~] = peakfit(signal1,0,0,2,1,0,3,app.first_guess.b4c1,1,0,0);         
                        [FitResults2, ~] = peakfit(signal2,0,0,2,1,0,3,app.first_guess.b4c2,1,0,0);         
                        [FitResults4, ~] = peakfit(signal4,0,0,3,1,0,3,app.first_guess.b4c4,1,0,0);
            
                        app.fit_data(j,i) = mat2cell([FitResults1; FitResults2; FitResults4], 7, 5);
                    
                    elseif classMap(j,i) == 4
                        [~,sic_start] = min(abs(wavelength - 557.97));
                        [~,sic_end] = min(abs(wavelength - 563.57));
                        sic_signal = [wavelength(sic_start:sic_end) squeeze(app.dcube(j,i,sic_start:sic_end))];
            
                        [FitResults, ~] = peakfit(sic_signal,0,0,1,1,0,3,app.first_guess.sic,1,0,0);            
                        app.fit_data(j,i) = mat2cell(FitResults, 1, 5);
                    end
                end
            end
        end

        % Value changed function: PhaseDropDown
        function PhaseDropDownValueChanged(app, event)
            value = app.PhaseDropDown.Value;
            
        end

        % Value changed function: PeakDropDown
        function PeakDropDownValueChanged(app, event)
            value = app.PeakDropDown.Value;
            
        end

        % Button pushed function: SaveStressMapButton
        function SaveStressMapButtonPushed(app, event)
            % Increment the number of saved figures counter
            app.saved_figs = app.saved_figs + 1;

            % Generate the new figure name
            figure_name = append(app.path, 'Raman Stress Map - ', int2str(app.saved_figs), '.png');

            % Save the figure
            export_fig(figure_name,'-transparent','-m5');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SaveStressMapButton = uibutton(app.UIFigure, 'push');
            app.SaveStressMapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStressMapButtonPushed, true);
            app.SaveStressMapButton.Text = 'Save Stress Map';
            app.LoadStandardButton = uibutton(app.UIFigure, 'push');
            app.LoadStandardButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStandardButtonPushed, true);
            app.LoadStandardButton.Text = 'Load Standard';
            app.PhaseLabel = uilabel(app.UIFigure);
            app.PhaseLabel.Text = 'Phase:';
            app.PhaseDropDown = uidropdown(app.UIFigure);
            app.PhaseDropDown.Items = {'', 'B4C', 'Si', 'SiB6', 'SiC'};
            app.PhaseDropDown.ValueChangedFcn = createCallbackFcn(app, @PhaseDropDownValueChanged, true);
            app.PhaseDropDown.Value = '';
            app.PeakLabel = uilabel(app.UIFigure);
            app.PeakLabel.Text = 'Peak:';
            app.PeakDropDown = uidropdown(app.UIFigure);
            app.PeakDropDown.Items = {};
            app.PeakDropDown.ValueChangedFcn = createCallbackFcn(app, @PeakDropDownValueChanged, true);
            app.PeakDropDown.Value = {};
            app.CLimLabel = uilabel(app.UIFigure);
            app.CLimLabel.Text = 'C1:';
            app.C1EditField = uieditfield(app.UIFigure, 'numeric');
            app.C2EditFieldLabel = uilabel(app.UIFigure);
            app.C2EditFieldLabel.Text = 'C2:';
            app.C2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Stress_Map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end